classdef SmartEcoGymApp_Team_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        YogaButton                     matlab.ui.control.Button
        returnGenStats                 matlab.ui.control.Button
        WalkingButton                  matlab.ui.control.Button
        RunningButton                  matlab.ui.control.Button
        EllipticalButton               matlab.ui.control.Button
        CyclingButton                  matlab.ui.control.Button
        WeightliftingButton            matlab.ui.control.Button
        BodyweightButton               matlab.ui.control.Button
        Suggestions                    matlab.ui.control.TextArea
        UniqueIDEditField              matlab.ui.control.EditField
        UniqueIDEditFieldLabel         matlab.ui.control.Label
        Image_2                        matlab.ui.control.Image
        Activity_Display               matlab.ui.control.Image
        User_Info                      matlab.ui.control.TextArea
        TitleBar_1                     matlab.ui.control.Image
        SmartEcoGymUserAnalyticsLabel  matlab.ui.control.Label
        UserAnalytics                  matlab.ui.control.TextArea
        WorkoutEfficiencyGraph         matlab.ui.control.UIAxes
        CalBurned_vs_PersonAvg         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        peopleTable     % people data converted into table data type
        sessionsTable   % sessions data converted into table data type
        activityTable   % activity_MET_reference data converted into table data type
        fullSessionData % the complete data table with each session also
                        % having the important activity and people data
        
        currPersonID    % keeps track of the current Person ID entered in app
        currActivitySelect  %keeps track of activity user selected on workout graphic
    end
    
    methods (Access = private)
        
        function plotCalBurnedVAvg(app)

            % Gets rows of data only where currPersonID and
            % currActivitySelect match

            if(strcmp(app.currActivitySelect, 'general'))
                personActivityData = app.fullSessionData(string(app.fullSessionData.person_id) ...
                                                        == string(app.currPersonID), :);
            else
                personActivityData = app.fullSessionData(string(app.fullSessionData.person_id) == string(app.currPersonID) & ...
                    string(app.fullSessionData.activity_type) == string(app.currActivitySelect), :);
            end
            personAvtivityAvgCal = mean(personActivityData.calBurned, 'omitnan');

            ax = app.CalBurned_vs_PersonAvg;

            bar(ax, personActivityData.calBurned, 'FaceColor', [1.0000 0.4196 0.2902]);
            hold(ax, 'on');

            yline(ax, personAvtivityAvgCal, 'w--', 'LineWidth', 2, ...
                'Label', sprintf('Avg: %.1f kcal', personAvtivityAvgCal), ...
                'LabelVerticalAlignment', 'bottom');
            hold(ax, 'off');

            ax.XTick = 1:height(personActivityData);
            ax.XColor = [0.9294 0.9020 0.8549];
            ax.XTickLabel = string(personActivityData.session_id);
            ax.XTickLabelRotation = 45;
            ax.XLabel.String = 'Session ID';
            ax.YColor = [0.9294 0.9020 0.8549];
            ax.YLabel.String = 'Calories Burned (kcal)';
            ax.Title.Color = [0.9294 0.9020 0.8549];
            ax.Title.String = sprintf('Member %s (%s): Calories vs Personal Avg', ...
                                        app.currPersonID, app.currActivitySelect);
            grid(ax, 'on');
        end

        function plotWorkoutEfficiency(app)
            
            currPersonSessions = app.fullSessionData(string(app.fullSessionData.person_id) == string(app.currPersonID), :);

            activitySummary = groupsummary(currPersonSessions, 'activity_type', 'mean', 'calRate_per_hr');

            [~, sortIdx] = sort(activitySummary.mean_calRate_per_hr, 'descend');
            activitySummary = activitySummary(sortIdx, :);
            
            ax = app.WorkoutEfficiencyGraph;

            % Horizontal bar plot for clear category comparison
            barh(ax, categorical(activitySummary.activity_type), activitySummary.mean_calRate_per_hr,'FaceColor', [0.3098 0.8196 0.7686]); 

            % Add numerical data labels on top of each bar
            text(ax, activitySummary.mean_calRate_per_hr + 10, 1:height(activitySummary), string(num2str(activitySummary.mean_calRate_per_hr, '%.1f kcal/hr')), ...
                'VerticalAlignment', 'middle', 'FontSize', 9, 'FontWeight', 'bold');

            ax.XLabel.String = 'Average Burn Rate (kcal / hour)';
            ax.YLabel.String = 'Workout Type';
            ax.Title.String = 'Your Workout Efficiency';
            grid(ax, 'on');

            % Extend x-axis limit so labels fit inside plot area
            ax.XLim = [0 max(activitySummary.mean_calRate_per_hr) * 1.25];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            if ~isfile('final_generated_wearable_sensor_data.mat')
                uialert(app.UIFigure, 'Data file not found. Please download it from the link in the README and place it in the app folder.', 'Missing Data File');
                return
            end

            rawData = load("final_generated_wearable_sensor_data.mat");
            app.currActivitySelect = 'general';

            % Converting raw data into correct Structs
            peopleStruct = [rawData.people{:}];
            sessionsStruct = [rawData.sessions{:}];
            activityStruct = [rawData.activity_met_reference{:}];

            % Converting Structs into tables
            app.peopleTable = struct2table(peopleStruct);
            app.sessionsTable = struct2table(sessionsStruct);
            app.activityTable = struct2table(activityStruct);

            
            % Joining peopleTable and activityTable onto sessionsTable to
            % create full session data
            app.fullSessionData = innerjoin(app.sessionsTable, app.peopleTable, 'Keys', 'person_id');
            app.fullSessionData = innerjoin(app.fullSessionData, app.activityTable, 'Keys', 'activity_type');

            % Calculations will go here. These will be reused throughout
            % the project, so we will only have to calculate them once: at
            % the start of the program.

            % Calculates duration of each workout and adds it to the table.
            app.fullSessionData.duration_hr = (double(app.fullSessionData.end_time_sec) - ...
                                                double(app.fullSessionData.start_time_sec)) / 3600.0;
            
            % Calculates calBurned of each workout and adds it to the table
            % using the formula MET * weight * duration from GitHub guide.
            app.fullSessionData.calBurned = app.fullSessionData.typical_MET .* app.fullSessionData.weight_kg .* ...
                                            double(app.fullSessionData.duration_hr);
            
            % Calculates workout efficiency in calBurned per hr of each
            % session and adds it to the table
            app.fullSessionData.calRate_per_hr = app.fullSessionData.calBurned ./ app.fullSessionData.duration_hr;

        end

        % Value changed function: UniqueIDEditField
        function UniqueIDEditFieldValueChanged(app, event)
            value = app.UniqueIDEditField.Value;

            
            % finding index where user entered value resides
            personIdx = strcmp(app.peopleTable.person_id, value);

            if ~any(personIdx)
                app.UniqueIDEditField.Placeholder = 'Try Again: ID Does Not Exist';
            else
                % Sets current person ID to what user entered
                app.currPersonID = value;

                % Gets the row where person's ID index matches
                personRow = app.peopleTable(personIdx, :);

                % Writes user info from the row above into User Info Box
               resultStr = sprintf(['User Info \n\n\n\n\n' ...
                                    'Age:    %d years old\n' ...
                                    'Height:     %.1f cm\n' ...
                                    'Weight:     %.1f kg\n\n\n\n' ...
                                    'Fitness Goal:   %s\n' ...
                                    'Fitness Level:  %s\n\n\n\n' ...
                                    'Resting BPM:    %.4f bpm\n' ...
                                    'Max BPM:    %.4f bpm\n' ...
                                    'BMR:    %.0f kcal/day\n'], ...
                                    personRow.age_years, ...
                                    personRow.height_cm, ...
                                    personRow.weight_kg, ...
                                    personRow.fitness_goal{1}, ...
                                    personRow.fitness_level{1}, ...
                                    personRow.resting_hr_bpm, ...
                                    personRow.estimated_max_hr_bpm, ...
                                    personRow.bmr_kcal_day);
                
                app.User_Info.Value = resultStr;

                plotCalBurnedVAvg(app);
                plotWorkoutEfficiency(app);
                app.CalBurned_vs_PersonAvg.Visible = "on";
                app.WorkoutEfficiencyGraph.Visible = "on";
            end
        end

        % Button pushed function: WalkingButton
        function WalkingButtonPushed(app, event)
            app.currActivitySelect = 'walking';
            plotCalBurnedVAvg(app);
        end

        % Button pushed function: returnGenStats
        function returnGenStatsButtonPushed(app, event)
            app.currActivitySelect = 'general';
            plotCalBurnedVAvg(app);
        end

        % Button pushed function: RunningButton
        function RunningButtonPushed(app, event)
            app.currActivitySelect = 'treadmill_running';
            plotCalBurnedVAvg(app);
        end

        % Button pushed function: CyclingButton
        function CyclingButtonPushed(app, event)
            app.currActivitySelect = 'cycling';
            plotCalBurnedVAvg(app);
        end

        % Button pushed function: EllipticalButton
        function EllipticalButtonPushed(app, event)
            app.currActivitySelect = 'elliptical';
            plotCalBurnedVAvg(app);
        end

        % Button pushed function: WeightliftingButton
        function WeightliftingButtonPushed(app, event)
            app.currActivitySelect = 'weight_lifting';
            plotCalBurnedVAvg(app);
        end

        % Button pushed function: BodyweightButton
        function BodyweightButtonPushed(app, event)
            app.currActivitySelect = 'bodyweight_training';
            plotCalBurnedVAvg(app);
        end

        % Button pushed function: YogaButton
        function YogaButtonPushed(app, event)
            app.currActivitySelect = 'yoga';
            plotCalBurnedVAvg(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalBurned_vs_PersonAvg = uiaxes(app.UIFigure);
            title(app.CalBurned_vs_PersonAvg, 'Title')
            xlabel(app.CalBurned_vs_PersonAvg, 'X')
            ylabel(app.CalBurned_vs_PersonAvg, 'Y')
            zlabel(app.CalBurned_vs_PersonAvg, 'Z')
            app.CalBurned_vs_PersonAvg.Visible = 'off';
            app.WorkoutEfficiencyGraph = uiaxes(app.UIFigure);
            title(app.WorkoutEfficiencyGraph, 'Title')
            xlabel(app.WorkoutEfficiencyGraph, 'X')
            ylabel(app.WorkoutEfficiencyGraph, 'Y')
            zlabel(app.WorkoutEfficiencyGraph, 'Z')
            app.WorkoutEfficiencyGraph.Visible = 'off';
            app.UserAnalytics = uitextarea(app.UIFigure);
            app.UserAnalytics.Editable = 'off';
            app.UserAnalytics.Value = {'User Analytics'; ''};
            app.SmartEcoGymUserAnalyticsLabel = uilabel(app.UIFigure);
            app.SmartEcoGymUserAnalyticsLabel.Text = 'Smart Eco Gym User Analytics';
            app.TitleBar_1 = uiimage(app.UIFigure);
            app.TitleBar_1.ImageSource = fullfile(pathToMLAPP, 'ecogymLine.png');
            app.User_Info = uitextarea(app.UIFigure);
            app.User_Info.Editable = 'off';
            app.User_Info.Value = {'User Info'};
            app.Activity_Display = uiimage(app.UIFigure);
            app.Activity_Display.ImageSource = fullfile(pathToMLAPP, 'smartEcoGymActivity.png');
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'ecogymLine.png');
            app.UniqueIDEditFieldLabel = uilabel(app.UIFigure);
            app.UniqueIDEditFieldLabel.Text = 'Unique ID:';
            app.UniqueIDEditField = uieditfield(app.UIFigure, 'text');
            app.UniqueIDEditField.ValueChangedFcn = createCallbackFcn(app, @UniqueIDEditFieldValueChanged, true);
            app.UniqueIDEditField.Placeholder = 'Enter Your Anonymous ID';
            app.Suggestions = uitextarea(app.UIFigure);
            app.Suggestions.Editable = 'off';
            app.Suggestions.Value = {'Suggestions'; ''};
            app.BodyweightButton = uibutton(app.UIFigure, 'push');
            app.BodyweightButton.ButtonPushedFcn = createCallbackFcn(app, @BodyweightButtonPushed, true);
            app.BodyweightButton.Text = 'Click Me';
            app.WeightliftingButton = uibutton(app.UIFigure, 'push');
            app.WeightliftingButton.ButtonPushedFcn = createCallbackFcn(app, @WeightliftingButtonPushed, true);
            app.WeightliftingButton.Text = 'Click Me';
            app.CyclingButton = uibutton(app.UIFigure, 'push');
            app.CyclingButton.ButtonPushedFcn = createCallbackFcn(app, @CyclingButtonPushed, true);
            app.CyclingButton.Text = 'Click Me';
            app.EllipticalButton = uibutton(app.UIFigure, 'push');
            app.EllipticalButton.ButtonPushedFcn = createCallbackFcn(app, @EllipticalButtonPushed, true);
            app.EllipticalButton.Text = 'Click Me';
            app.RunningButton = uibutton(app.UIFigure, 'push');
            app.RunningButton.ButtonPushedFcn = createCallbackFcn(app, @RunningButtonPushed, true);
            app.RunningButton.Text = 'Click Me';
            app.WalkingButton = uibutton(app.UIFigure, 'push');
            app.WalkingButton.ButtonPushedFcn = createCallbackFcn(app, @WalkingButtonPushed, true);
            app.WalkingButton.Text = 'Click Me';
            app.returnGenStats = uibutton(app.UIFigure, 'push');
            app.returnGenStats.ButtonPushedFcn = createCallbackFcn(app, @returnGenStatsButtonPushed, true);
            app.returnGenStats.Text = 'Return To General Stats';
            app.YogaButton = uibutton(app.UIFigure, 'push');
            app.YogaButton.ButtonPushedFcn = createCallbackFcn(app, @YogaButtonPushed, true);
            app.YogaButton.Text = 'Click Me';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SmartEcoGymApp_Team_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end